classdef LabAssignment2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        qControlPanel              matlab.ui.container.Panel
        slider1                    matlab.ui.control.Slider
        Q1EditFieldLabel           matlab.ui.control.Label
        dispQ1                     matlab.ui.control.NumericEditField
        slider2                    matlab.ui.control.Slider
        Q2Label                    matlab.ui.control.Label
        dispQ2                     matlab.ui.control.NumericEditField
        slider3                    matlab.ui.control.Slider
        Q3Label                    matlab.ui.control.Label
        dispQ3                     matlab.ui.control.NumericEditField
        slider4                    matlab.ui.control.Slider
        Q4Label                    matlab.ui.control.Label
        dispQ4                     matlab.ui.control.NumericEditField
        slider5                    matlab.ui.control.Slider
        Q5Label                    matlab.ui.control.Label
        dispQ5                     matlab.ui.control.NumericEditField
        slider6                    matlab.ui.control.Slider
        Q6Label                    matlab.ui.control.Label
        dispQ6                     matlab.ui.control.NumericEditField
        incrementQ1                matlab.ui.control.Button
        decrementQ1                matlab.ui.control.Button
        incrementQ2                matlab.ui.control.Button
        incrementQ5                matlab.ui.control.Button
        incrementQ4                matlab.ui.control.Button
        incrementQ3                matlab.ui.control.Button
        incrementQ6                matlab.ui.control.Button
        decrementQ2                matlab.ui.control.Button
        decrementQ3                matlab.ui.control.Button
        decrementQ4                matlab.ui.control.Button
        decrementQ5                matlab.ui.control.Button
        decrementQ6                matlab.ui.control.Button
        eStopButton                matlab.ui.control.StateButton
        CartesianMovementPanel     matlab.ui.container.Panel
        XEditFieldLabel            matlab.ui.control.Label
        inputX                     matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        inputY                     matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        inputZ                     matlab.ui.control.NumericEditField
        GOButton_cartesian         matlab.ui.control.Button
        SelectplayerPanel          matlab.ui.container.Panel
        ROLLButton                 matlab.ui.control.Button
        GOButton_dice              matlab.ui.control.Button
        dispDice_1                 matlab.ui.control.NumericEditField
        dispDice_2                 matlab.ui.control.NumericEditField
        TotalEditFieldLabel        matlab.ui.control.Label
        dispTotal                  matlab.ui.control.NumericEditField
        PlayernumberDropDownLabel  matlab.ui.control.Label
        PlayerNumberDropDown       matlab.ui.control.DropDown
        PlotLabel                  matlab.ui.control.Label
        CardsLabel                 matlab.ui.control.Label
        EditFieldLabel             matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        INITIALIZEButton           matlab.ui.control.Button
        SensorradiusSliderLabel    matlab.ui.control.Label
        SensorradiusSlider         matlab.ui.control.Slider
        Button                     matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        PersonButton               matlab.ui.control.Button
        BrickButton                matlab.ui.control.Button
        EditField_2Label           matlab.ui.control.Label
        EditField_2                matlab.ui.control.NumericEditField
        EditField_3Label           matlab.ui.control.Label
        EditField_3                matlab.ui.control.NumericEditField
        EditField_4Label           matlab.ui.control.Label
        EditField_4                matlab.ui.control.NumericEditField
        PlayerLabel                matlab.ui.control.Label
    end

    properties (Access = public)
        board
        cell
        kinova 
        var
        playersLocation
        playerNumber
        pieces
        brick
        person
        circumference
        draw
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: INITIALIZEButton
        function INITIALIZEButtonPushed(app, event)
            ClearFunction();
            [app.board,app.cell,app.kinova,app.var,app.pieces] = InitiateRobot();
            app.playersLocation = ones(4);
            app.draw = [false,false];   % Don't draw or move the brick and person
        end

        % Button pushed function: BrickButton
        function BrickButtonPushed(app, event)
            % Plot the brick, update the position with the phone
            app.brick.pos = [0,-0.5,0];
            app.brick = LocateParts(app.brick,'brick');
            app.draw(1) = true;
        end

        % Button pushed function: PersonButton
        function PersonButtonPushed(app, event)
            % Plot the person, update the position with the phone
            app.person.pos = [0,2,-0.755];
            app.person.phi = 0;
            app.person = LocateParts(app.person,'person');
            app.draw(2) = true;
        end

        % Value changed function: eStopButton
        function eStopRead(app, event)
            EStopState(1);   % Type 1 = Stop button pressed 
        end

        % Button pushed function: ROLLButton
        function ROLLButtonPushed(app, event)
            app.dispDice_1.Value = randi([1 6]);
            app.dispDice_2.Value = randi([1 6]);
            app.dispTotal.Value = app.dispDice_1.Value + app.dispDice_2.Value;
        end

        % Value changed function: PlayerNumberDropDown
        function PlayerNumberDropDownValueChanged(app, event)
            app.playerNumber = str2num(app.PlayerNumberDropDown.Value);
            switch app.playerNumber
                case 1
                    disp('Player 1 selected')
                case 2
                    disp('Player 2 selected')
                case 3
                    disp('Player 3 selected')
                case 4
                    disp('Player 4 selected')
            end
        end

        % Button pushed function: GOButton_dice
        function GOButton_dicePushed(app, event)
            aux = ['piece' num2str(app.playerNumber)];
            app.playersLocation(app.playerNumber) = Movement(app.playersLocation,app.playerNumber,app.dispTotal.Value,app.board,app.cell,app.kinova,app.var,app.pieces.(aux),app.brick);
        end

        % Button pushed function: GOButton_cartesian
        function GOButton_cartesianPushed(app, event)
            xValue = app.inputX.Value;
            yValue = app.inputY.Value;
            zValue = app.inputZ.Value;
            CartesianControl(app.kinova,xValue,yValue,zValue);
        end

        % Value changed function: SensorradiusSlider
        function SensorradiusSliderValueChanged(app, event)
            radius = app.SensorradiusSlider.Value;
            try delete(app.circumference); end
            c = circle([0,0],radius);
            app.circumference = plot(c(1,:),c(2,:),'b',"LineWidth",2);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if app.draw(1) == true
                x1 = app.brick.pos(1);
                y1 = app.brick.pos(2);
                z1 = app.brick.pos(3);
            else
                x1 = 0;
                y1 = 0;
                z1 = 0;
            end
            if app.draw(2) == true
                x2 = app.person.pos(1);
                y2 = app.person.pos(2);
                phi = app.person.phi;
            else
                x2 = 0;
                y2 = 0;
                phi = 0;
            end          
            mov = 0.05;  % Movement m

            switch(key)
                % Brick
                case 'leftarrow'
                    x1 = x1 + mov;
                case 'rightarrow'
                    x1 = x1 - mov;
                case 'downarrow'
                    y1 = y1 + mov;
                case 'uparrow'
                    y1 = y1 - mov; 
                case 't'
                    z1 = z1 + mov;
                case 'g'
                    z1 = z1 - mov;
                % Person
                case 'i'
                    x2 = x2 + mov;
                case 'j'
                    x2 = x2 - mov;
                case 'k'
                    y2 = y2 + mov;
                case 'u'
                    y2 = y2 - mov;
                case 'y'
                    phi = phi + pi/16;
                case 'h'
                    phi = phi - pi/16;
            end
            
            if app.draw(1) == true
                app.brick.pos = [x1,y1,z1];
                app.brick.pose = makehgtform('translate',app.brick.pos);
                app.brick.updatedPoints = (app.brick.pose * [app.brick.verts,ones(app.brick.vertexCount,1)]')';
                app.brick.mesh_h.Vertices = app.brick.updatedPoints(:,1:3);
            end
            if app.draw(2) == true
                app.person.pos = [x2,y2,-0.755];
                app.person.phi = phi;
                app.person.pose = trotz(phi) * makehgtform('translate',app.person.pos);
                app.person.updatedPoints = (app.person.pose * [app.person.verts,ones(app.person.vertexCount,1)]')';
                app.person.mesh_h.Vertices = app.person.updatedPoints(:,1:3);
                if sqrt(x2^2 + y2^2) < app.SensorradiusSlider.Value
                    EStopState(3)
                end
            end
        end

        % Button pushed function: Button
        function chestPushed(app, event)
            ChestOrChance(app.kinova,1);
        end

        % Button pushed function: Button_2
        function chancePushed(app, event)
            ChestOrChance(app.kinova,2);
        end

        % Button pushed function: incrementQ1
        function incrementQ1ButtonPushed(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            q1Value = q1Value+deg2rad(5);
            app.slider1.Value = rad2deg(q1Value);
            app.dispQ1.Value = rad2deg(q1Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Button pushed function: incrementQ2
        function incrementQ2ButtonPushed(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            q2Value = q2Value+deg2rad(5);
            app.slider2.Value = rad2deg(q2Value);
            app.dispQ2.Value = rad2deg(q2Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Button pushed function: incrementQ3
        function incrementQ3ButtonPushed(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            q3Value = q3Value+deg2rad(5);
            app.slider3.Value = rad2deg(q3Value);
            app.dispQ3.Value = rad2deg(q3Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Button pushed function: incrementQ4
        function incrementQ4ButtonPushed(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            q4Value = q4Value+deg2rad(5);
            app.slider4.Value = rad2deg(q4Value);
            app.dispQ4.Value = rad2deg(q4Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Button pushed function: incrementQ5
        function incrementQ5ButtonPushed(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            q5Value = q5Value+deg2rad(5);
            app.slider5.Value = rad2deg(q5Value);
            app.dispQ5.Value = rad2deg(q5Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Button pushed function: incrementQ6
        function incrementQ6ButtonPushed(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            q6Value = q6Value+deg2rad(5);
            app.slider6.Value = rad2deg(q6Value);
            app.dispQ6.Value = rad2deg(q6Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Button pushed function: decrementQ1
        function decrementQ1ButtonPushed(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            q1Value = q1Value-deg2rad(5);
            app.slider1.Value = rad2deg(q1Value);
            app.dispQ1.Value = rad2deg(q1Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Button pushed function: decrementQ2
        function decrementQ2ButtonPushed(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            q2Value = q2Value-deg2rad(5);
            app.slider2.Value = rad2deg(q2Value);
            app.dispQ2.Value = rad2deg(q2Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Button pushed function: decrementQ3
        function decrementQ3ButtonPushed(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            q3Value = q3Value-deg2rad(5);
            app.slider3.Value = rad2deg(q3Value);
            app.dispQ3.Value = rad2deg(q3Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Button pushed function: decrementQ4
        function decrementQ4ButtonPushed(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            q4Value = q4Value-deg2rad(5);
            app.slider4.Value = rad2deg(q4Value);
            app.dispQ4.Value = rad2deg(q4Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Button pushed function: decrementQ5
        function decrementQ5ButtonPushed(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            q5Value = q5Value-deg2rad(5);
            app.slider5.Value = rad2deg(q5Value);
            app.dispQ5.Value = rad2deg(q5Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Button pushed function: decrementQ6
        function decrementQ6ButtonPushed(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            q6Value = q6Value-deg2rad(5);
            app.slider6.Value = rad2deg(q6Value);
            app.dispQ6.Value = rad2deg(q6Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Callback function: slider1, slider2, slider3, slider4, 
        % slider5, slider6, slider6
        function updateSliders(app, event)
            q1Value = deg2rad(app.slider1.Value);
            q2Value = deg2rad(app.slider2.Value);
            q3Value = deg2rad(app.slider3.Value);
            q4Value = deg2rad(app.slider4.Value);
            q5Value = deg2rad(app.slider5.Value);
            q6Value = deg2rad(app.slider6.Value);
            
            app.dispQ1.Value = rad2deg(q1Value);
            app.dispQ2.Value = rad2deg(q2Value);
            app.dispQ3.Value = rad2deg(q3Value);
            app.dispQ4.Value = rad2deg(q4Value);
            app.dispQ5.Value = rad2deg(q5Value);
            app.dispQ6.Value = rad2deg(q6Value);
            
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.qControlPanel = uipanel(app.UIFigure);
            app.qControlPanel.Title = 'q Control';
            app.slider1 = uislider(app.qControlPanel);
            app.slider1.Limits = [-360 360];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.Q1EditFieldLabel = uilabel(app.qControlPanel);
            app.Q1EditFieldLabel.Enable = 'off';
            app.Q1EditFieldLabel.Text = 'Q1';
            app.dispQ1 = uieditfield(app.qControlPanel, 'numeric');
            app.dispQ1.Limits = [-360 360];
            app.dispQ1.Enable = 'off';
            app.slider2 = uislider(app.qControlPanel);
            app.slider2.Limits = [-150 150];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.Q2Label = uilabel(app.qControlPanel);
            app.Q2Label.Enable = 'off';
            app.Q2Label.Text = 'Q2';
            app.dispQ2 = uieditfield(app.qControlPanel, 'numeric');
            app.dispQ2.Limits = [-150 150];
            app.dispQ2.Enable = 'off';
            app.slider3 = uislider(app.qControlPanel);
            app.slider3.Limits = [-150 150];
            app.slider3.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.Q3Label = uilabel(app.qControlPanel);
            app.Q3Label.Enable = 'off';
            app.Q3Label.Text = 'Q3';
            app.dispQ3 = uieditfield(app.qControlPanel, 'numeric');
            app.dispQ3.Limits = [-150 150];
            app.dispQ3.Enable = 'off';
            app.slider4 = uislider(app.qControlPanel);
            app.slider4.Limits = [-148 148];
            app.slider4.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.Q4Label = uilabel(app.qControlPanel);
            app.Q4Label.Enable = 'off';
            app.Q4Label.Text = 'Q4';
            app.dispQ4 = uieditfield(app.qControlPanel, 'numeric');
            app.dispQ4.Limits = [-150 150];
            app.dispQ4.Enable = 'off';
            app.slider5 = uislider(app.qControlPanel);
            app.slider5.Limits = [-144 145];
            app.slider5.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.Q5Label = uilabel(app.qControlPanel);
            app.Q5Label.Enable = 'off';
            app.Q5Label.Text = 'Q5';
            app.dispQ5 = uieditfield(app.qControlPanel, 'numeric');
            app.dispQ5.Limits = [-150 150];
            app.dispQ5.Enable = 'off';
            app.slider6 = uislider(app.qControlPanel);
            app.slider6.Limits = [-148 148];
            app.slider6.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.slider6.ValueChangingFcn = createCallbackFcn(app, @updateSliders, true);
            app.Q6Label = uilabel(app.qControlPanel);
            app.Q6Label.Enable = 'off';
            app.Q6Label.Text = 'Q6';
            app.dispQ6 = uieditfield(app.qControlPanel, 'numeric');
            app.dispQ6.Limits = [-150 150];
            app.dispQ6.Enable = 'off';
            app.incrementQ1 = uibutton(app.qControlPanel, 'push');
            app.incrementQ1.ButtonPushedFcn = createCallbackFcn(app, @incrementQ1ButtonPushed, true);
            app.incrementQ1.Text = '+';
            app.decrementQ1 = uibutton(app.qControlPanel, 'push');
            app.decrementQ1.ButtonPushedFcn = createCallbackFcn(app, @decrementQ1ButtonPushed, true);
            app.decrementQ1.Text = '-';
            app.incrementQ2 = uibutton(app.qControlPanel, 'push');
            app.incrementQ2.ButtonPushedFcn = createCallbackFcn(app, @incrementQ2ButtonPushed, true);
            app.incrementQ2.Text = '+';
            app.incrementQ5 = uibutton(app.qControlPanel, 'push');
            app.incrementQ5.ButtonPushedFcn = createCallbackFcn(app, @incrementQ5ButtonPushed, true);
            app.incrementQ5.Text = '+';
            app.incrementQ4 = uibutton(app.qControlPanel, 'push');
            app.incrementQ4.ButtonPushedFcn = createCallbackFcn(app, @incrementQ4ButtonPushed, true);
            app.incrementQ4.Text = '+';
            app.incrementQ3 = uibutton(app.qControlPanel, 'push');
            app.incrementQ3.ButtonPushedFcn = createCallbackFcn(app, @incrementQ3ButtonPushed, true);
            app.incrementQ3.Text = '+';
            app.incrementQ6 = uibutton(app.qControlPanel, 'push');
            app.incrementQ6.ButtonPushedFcn = createCallbackFcn(app, @incrementQ6ButtonPushed, true);
            app.incrementQ6.Text = '+';
            app.decrementQ2 = uibutton(app.qControlPanel, 'push');
            app.decrementQ2.ButtonPushedFcn = createCallbackFcn(app, @decrementQ2ButtonPushed, true);
            app.decrementQ2.Text = '-';
            app.decrementQ3 = uibutton(app.qControlPanel, 'push');
            app.decrementQ3.ButtonPushedFcn = createCallbackFcn(app, @decrementQ3ButtonPushed, true);
            app.decrementQ3.Text = '-';
            app.decrementQ4 = uibutton(app.qControlPanel, 'push');
            app.decrementQ4.ButtonPushedFcn = createCallbackFcn(app, @decrementQ4ButtonPushed, true);
            app.decrementQ4.Text = '-';
            app.decrementQ5 = uibutton(app.qControlPanel, 'push');
            app.decrementQ5.ButtonPushedFcn = createCallbackFcn(app, @decrementQ5ButtonPushed, true);
            app.decrementQ5.Text = '-';
            app.decrementQ6 = uibutton(app.qControlPanel, 'push');
            app.decrementQ6.ButtonPushedFcn = createCallbackFcn(app, @decrementQ6ButtonPushed, true);
            app.decrementQ6.Text = '-';
            app.eStopButton = uibutton(app.UIFigure, 'state');
            app.eStopButton.ValueChangedFcn = createCallbackFcn(app, @eStopRead, true);
            app.eStopButton.Icon = 'eStop.JPG';
            app.eStopButton.Text = '';
            app.CartesianMovementPanel = uipanel(app.UIFigure);
            app.CartesianMovementPanel.Title = 'Cartesian inputs';
            app.XEditFieldLabel = uilabel(app.CartesianMovementPanel);
            app.XEditFieldLabel.Text = 'X';
            app.inputX = uieditfield(app.CartesianMovementPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.CartesianMovementPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.inputY = uieditfield(app.CartesianMovementPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.CartesianMovementPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.inputZ = uieditfield(app.CartesianMovementPanel, 'numeric');
            app.GOButton_cartesian = uibutton(app.CartesianMovementPanel, 'push');
            app.GOButton_cartesian.ButtonPushedFcn = createCallbackFcn(app, @GOButton_cartesianPushed, true);
            app.GOButton_cartesian.Text = 'GO';
            app.SelectplayerPanel = uipanel(app.UIFigure);
            app.SelectplayerPanel.Title = 'Select player';
            app.ROLLButton = uibutton(app.SelectplayerPanel, 'push');
            app.ROLLButton.ButtonPushedFcn = createCallbackFcn(app, @ROLLButtonPushed, true);
            app.ROLLButton.Text = 'ROLL';
            app.GOButton_dice = uibutton(app.SelectplayerPanel, 'push');
            app.GOButton_dice.ButtonPushedFcn = createCallbackFcn(app, @GOButton_dicePushed, true);
            app.GOButton_dice.Text = 'GO';
            app.dispDice_1 = uieditfield(app.SelectplayerPanel, 'numeric');
            app.dispDice_1.Editable = 'off';
            app.dispDice_2 = uieditfield(app.SelectplayerPanel, 'numeric');
            app.dispDice_2.Editable = 'off';
            app.TotalEditFieldLabel = uilabel(app.SelectplayerPanel);
            app.TotalEditFieldLabel.Text = 'Total';
            app.dispTotal = uieditfield(app.SelectplayerPanel, 'numeric');
            app.PlayernumberDropDownLabel = uilabel(app.SelectplayerPanel);
            app.PlayernumberDropDownLabel.Text = 'Player number';
            app.PlayerNumberDropDown = uidropdown(app.SelectplayerPanel);
            app.PlayerNumberDropDown.Items = {'...', '1', '2', '3', '4'};
            app.PlayerNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @PlayerNumberDropDownValueChanged, true);
            app.PlayerNumberDropDown.Value = '...';
            app.INITIALIZEButton = uibutton(app.UIFigure, 'push');
            app.INITIALIZEButton.ButtonPushedFcn = createCallbackFcn(app, @INITIALIZEButtonPushed, true);
            app.INITIALIZEButton.Icon = 'on.png';
            app.INITIALIZEButton.Text = 'INITIALIZE';
            app.SensorradiusSliderLabel = uilabel(app.UIFigure);
            app.SensorradiusSliderLabel.Text = 'Sensor radius';
            app.SensorradiusSlider = uislider(app.UIFigure);
            app.SensorradiusSlider.Limits = [0.4 2];
            app.SensorradiusSlider.ValueChangedFcn = createCallbackFcn(app, @SensorradiusSliderValueChanged, true);
            app.SensorradiusSlider.Value = 0.4;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @chestPushed, true);
            app.Button.Icon = 'chest.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @chancePushed, true);
            app.Button_2.Icon = 'chance.jpg';
            app.Button_2.Text = '';
            app.PersonButton = uibutton(app.UIFigure, 'push');
            app.PersonButton.ButtonPushedFcn = createCallbackFcn(app, @PersonButtonPushed, true);
            app.PersonButton.Text = 'Person';
            app.BrickButton = uibutton(app.UIFigure, 'push');
            app.BrickButton.ButtonPushedFcn = createCallbackFcn(app, @BrickButtonPushed, true);
            app.BrickButton.Text = 'Brick';
            app.PlotLabel = uilabel(app.UIFigure);
            app.PlotLabel.Text = 'Plot';
            app.CardsLabel = uilabel(app.UIFigure);
            app.CardsLabel.Text = 'Cards';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '1';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '2';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '3';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '4';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.PlayerLabel = uilabel(app.UIFigure);
            app.PlayerLabel.Text = 'Player';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabAssignment2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end